classdef App_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Vx0Slider              matlab.ui.control.Slider
        Vx0SliderLabel         matlab.ui.control.Label
        x0Slider               matlab.ui.control.Slider
        x0SliderLabel          matlab.ui.control.Label
        EndeligeresultatPanel  matlab.ui.container.Panel
        Yslutt                 matlab.ui.control.NumericEditField
        YsluttLabel            matlab.ui.control.Label
        Xslutt                 matlab.ui.control.NumericEditField
        XsluttLabel            matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        RunButton              matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: RunButton, x0Slider
        function RunButtonPushed(app, event)
cla(app.UIAxes);            
%app.Label.Text="simulating";
pause(0.01);
f =@(x) x.^2/4 - 4 * cos(x-1);
xlabel(app.UIAxes,"X-axis",'fontsize',16,'color','b');
ylabel(app.UIAxes,"Y-axis",'fontsize',16,'color','b');
title(app.UIAxes,"Simulating of sliding",'fontsize',16,'color','r');

% X-posisjon som funksjon av tid
x0 = str2num(app.X0.Value) ;                                 % Startposisjon
Vx0 =str2num(app.Vx0.Value); 
app.Vx0.Value
%Start av farten
% Opplّsninga i tid - steglengda - og varigheten av simuleringa
dt = 0.005;
% mot venstre
xMin=-10;
xMax=10;
X =x0;
h = 0.001;
g = 9.81; 
M =0.1;
presisjon = 1e-8;
Vx = Vx0;                                   %Steglengde (blir endra inne i lّkka)
Vmin=1e-5;
% Vektor med x-verdier
Na=300;                                     % antall punkter
xVektor = linspace(xMin,xMax,Na);           % Vektor med x-verdier
% Lager plott
plot(app.UIAxes, xVektor,f(xVektor),'k-','linewidth',2) % Plotter landskap
hold(app.UIAxes,'on');
xVerdi = x0;
yVerdi = f(xVerdi);
% Ploter posisjonen til objektet (rّd stjerne)
pl = plot(app.UIAxes,xVerdi,yVerdi,'rx','linewidth',10);    
Xstopp= 0;
XstoppNy= 10*presisjon;
D=0;
while abs(Xstopp-XstoppNy)>presisjon
       Xstopp=XstoppNy;
    while abs(Vx) > Vmin || D > M          % Looper over alle tidspunktene
          N=Normalkraft(Vx,X,h,f,M,g);     %Normalkraft funksjon
          R=M*N;                           % Friksjon=Friksjonskoeffisient*Normalkraft
          D=(f(X+h)-f(X-h))/(2*h); 
          ax=Akselerasjon(Vx,X,h,f,M,g);   %Akselerasjon
          VxHatt=Vx+ax*dt/2;
          XHatt=X+Vx*dt/2;
          ax=Akselerasjon(VxHatt,XHatt,h,f,M,g);
          Vx=Vx+ax*dt;
          X=X+Vx*dt;
          Y=f(X);
          % Oppdaterer data til plotting
          set(pl,'xdata',X);
          set(pl,'ydata',Y);
      %drawnow                               % Oppdaterer selve plottet
     end
  XstoppNy=X;
  M=M/2;
  
end
disp(['Minimalpunkt: ',num2str(XstoppNy),'.'])
disp(['Minmialverdi: ',num2str(f(XstoppNy)),'.'])
hold(app.UIAxes,'off');
 %app.Label.Text="done";
app.Xslutt.Value=XstoppNy;
app.Yslutt.Value=f(XstoppNy);
% Lukker video-fila
%close(v)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'simulating sliding')
            xlabel(app.UIAxes, 'X axis')
            ylabel(app.UIAxes, 'Y axis')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EndeligeresultatPanel = uipanel(app.UIFigure);
            app.EndeligeresultatPanel.Title = 'Endelige resultat';
            app.XsluttLabel = uilabel(app.EndeligeresultatPanel);
            app.XsluttLabel.Text = 'Xslutt';
            app.Xslutt = uieditfield(app.EndeligeresultatPanel, 'numeric');
            app.YsluttLabel = uilabel(app.EndeligeresultatPanel);
            app.YsluttLabel.Text = 'Yslutt';
            app.Yslutt = uieditfield(app.EndeligeresultatPanel, 'numeric');
            app.x0SliderLabel = uilabel(app.UIFigure);
            app.x0SliderLabel.Text = 'x0';
            app.x0Slider = uislider(app.UIFigure);
            app.x0Slider.Limits = [-10 10];
            app.x0Slider.ValueChangedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Vx0SliderLabel = uilabel(app.UIFigure);
            app.Vx0SliderLabel.Text = 'Vx0';
            app.Vx0Slider = uislider(app.UIFigure);
            app.Vx0Slider.Limits = [-30 30];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end